classdef SPEKTRUM_UI_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CONSOLEIKTISITextArea       matlab.ui.control.TextArea
        CONSOLEIKTISITextAreaLabel  matlab.ui.control.Label
        RoketHamVeriler             matlab.ui.control.TextArea
        ROKETGELENVERLERBYTEARRAYTextAreaLabel  matlab.ui.control.Label
        HYIBauds_DropDown           matlab.ui.control.DropDown
        HYBAUDDropDownLabel         matlab.ui.control.Label
        HYIPorts_DropDown           matlab.ui.control.DropDown
        HYPORTDropDownLabel         matlab.ui.control.Label
        YukBauds_DropDown           matlab.ui.control.DropDown
        YKBAUDDropDownLabel         matlab.ui.control.Label
        YukPorts_DropDown           matlab.ui.control.DropDown
        YKPORTDropDownLabel         matlab.ui.control.Label
        RoketBauds_DropDown         matlab.ui.control.DropDown
        ROKETBAUDDropDownLabel      matlab.ui.control.Label
        RoketPorts_DropDown         matlab.ui.control.DropDown
        ROKETPORTDropDownLabel      matlab.ui.control.Label
        HYISwitch                   matlab.ui.control.ToggleSwitch
        HYSwitchLabel               matlab.ui.control.Label
        YukSwitch                   matlab.ui.control.ToggleSwitch
        YKSwitchLabel               matlab.ui.control.Label
        RoketSwitch                 matlab.ui.control.ToggleSwitch
        ROKETSwitchLabel            matlab.ui.control.Label
        HTML                        matlab.ui.control.HTML
        nanLabel_18                 matlab.ui.control.Label
        nanLabel_17                 matlab.ui.control.Label
        nanLabel_16                 matlab.ui.control.Label
        nanLabel_15                 matlab.ui.control.Label
        nanLabel_14                 matlab.ui.control.Label
        nanLabel_13                 matlab.ui.control.Label
        nanLabel_12                 matlab.ui.control.Label
        nanLabel_11                 matlab.ui.control.Label
        nanLabel_10                 matlab.ui.control.Label
        nanLabel_9                  matlab.ui.control.Label
        nanLabel_8                  matlab.ui.control.Label
        nanLabel_7                  matlab.ui.control.Label
        YkBoylamLabel               matlab.ui.control.Label
        YkEnlemLabel                matlab.ui.control.Label
        RoketGPSrtifaLabel          matlab.ui.control.Label
        RoketBoylamLabel            matlab.ui.control.Label
        RoketEnlemLabel             matlab.ui.control.Label
        rtifaLabel                  matlab.ui.control.Label
        AccelZLabel                 matlab.ui.control.Label
        AccelYLabel                 matlab.ui.control.Label
        AccelXLabel                 matlab.ui.control.Label
        GyroZLabel                  matlab.ui.control.Label
        GyroYLabel                  matlab.ui.control.Label
        GyroXLabel                  matlab.ui.control.Label
        Altimeter                   Aero.ui.control.Altimeter
        HeadingIndicator            Aero.ui.control.HeadingIndicator
        AirspeedIndicator           Aero.ui.control.AirspeedIndicator
        nanLabel_6                  matlab.ui.control.Label
        OkunmayBekleyenBLabel_3     matlab.ui.control.Label
        OkunanVeriSaysBLabel_3      matlab.ui.control.Label
        nanLabel_5                  matlab.ui.control.Label
        nanLabel_4                  matlab.ui.control.Label
        OkunmayBekleyenBLabel_2     matlab.ui.control.Label
        OkunanVeriSaysBLabel_2      matlab.ui.control.Label
        nanLabel_3                  matlab.ui.control.Label
        nanLabel_2                  matlab.ui.control.Label
        nanLabel                    matlab.ui.control.Label
        OkunanVeriSaysBLabel        matlab.ui.control.Label
        OkunmayBekleyenBLabel       matlab.ui.control.Label
        PortRefresh_Btn             matlab.ui.control.Button
        RoketTest_Btn               matlab.ui.control.Button
        TESTButton_2                matlab.ui.control.Button
        TESTButton                  matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Program başladığında çalışacak fonksiyondur
            %1 -> Seri Portları ve BaudRate değerlerini DropDown'a Ekler
            serialPorts = serialportlist();
            app.RoketPorts_DropDown.Items = serialPorts;
            app.YukPorts_DropDown.Items = serialPorts;
            app.HYIPorts_DropDown.Items = serialPorts;

            baudrates = ["9600", "14400", "19200", "38400", "57600", "115200"];
            app.RoketBauds_DropDown.Items = baudrates;
            app.YukBauds_DropDown.Items = baudrates;
            app.HYIBauds_DropDown.Items = baudrates;
        end

        % Button pushed function: PortRefresh_Btn
        function PortRefresh_BtnButtonPushed(app, event)
            % Portları Yenile Butonuna Basıldığında Seri Portların Olduğu
            % DropDown içeriği sıfırlanır ve yeni com portlar eklenir
            serialPorts = serialportlist();
            app.RoketPorts_DropDown.Items = {};
            app.YukPorts_DropDown.Items = {};
            app.HYIPorts_DropDown.Items = {};

            app.RoketPorts_DropDown.Items = serialPorts;
            app.YukPorts_DropDown.Items = serialPorts;
            app.HYIPorts_DropDown.Items = serialPorts;
        end

        % Value changed function: RoketSwitch
        function RoketSwitchValueChanged(app, event)
            %Roket İletişimi Başlayınca Çalışacak Olan CallBack
            %Bu alt fonksiyon Switch değerine göre seri iletişimde rol
            %alacaktır.
            value = app.RoketSwitch.Value;
            if value == "BASLA"
                app.RoketPorts_DropDown.Enable = "off";
                app.RoketBauds_DropDown.Enable = "off";
                serialPortValue = app.RoketPorts_DropDown.Value;
                baudRateValue = str2double(app.RoketBauds_DropDown.Value);
                try
                    SerialBaglanti = serialport(serialPortValue, baudRateValue);
                catch exception
                    warndlg(exception.message(),"SERİ PORT HATASI!11!1!","non-modal");
                    warndlg("SERİ BAĞLANTI BAŞLATILAMADI","SERİ PORT HATASI!11!1!","non-modal");
                    app.RoketPorts_DropDown.Enable = "on";
                    app.RoketBauds_DropDown.Enable = "on";
                    app.RoketSwitch.Value = "DUR";
                end
                    RoketData = char(read(SerialBaglanti, 57,"uint8"));
                    app.RoketHamVeriler.Value = RoketData;
            else
                app.RoketPorts_DropDown.Enable = "on";
                app.RoketBauds_DropDown.Enable = "on";
            end
        end

        % Button pushed function: RoketTest_Btn
        function RoketTest_BtnButtonPushed(app, event)
                serialPortValue = app.RoketPorts_DropDown.Value;
                baudRateValue = str2double(app.RoketBauds_DropDown.Value);
                try
                    serialport(serialPortValue, baudRateValue);
                    warndlg("SERİ BAĞLANTI BAŞARILI","SERİ PORT ÇALIŞIYOR!","non-modal");
                catch exception
                    warndlg(exception.message(),"SERİ PORT HATASI!11!1!","non-modal");
                    warndlg("SERİ BAĞLANTI BAŞLATILAMADI","SERİ PORT HATASI!11!1!","non-modal");
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.Text = 'TEST';
            app.TESTButton_2 = uibutton(app.UIFigure, 'push');
            app.TESTButton_2.Text = 'TEST';
            app.RoketTest_Btn = uibutton(app.UIFigure, 'push');
            app.RoketTest_Btn.ButtonPushedFcn = createCallbackFcn(app, @RoketTest_BtnButtonPushed, true);
            app.RoketTest_Btn.Text = 'TEST';
            app.PortRefresh_Btn = uibutton(app.UIFigure, 'push');
            app.PortRefresh_Btn.ButtonPushedFcn = createCallbackFcn(app, @PortRefresh_BtnButtonPushed, true);
            app.PortRefresh_Btn.Text = 'PORTLARI YENILE';
            app.OkunmayBekleyenBLabel = uilabel(app.UIFigure);
            app.OkunmayBekleyenBLabel.Text = 'Okunmayı Bekleyen(B):';
            app.OkunanVeriSaysBLabel = uilabel(app.UIFigure);
            app.OkunanVeriSaysBLabel.Text = 'Okunan Veri Sayısı(B):';
            app.nanLabel = uilabel(app.UIFigure);
            app.nanLabel.Text = 'nan';
            app.nanLabel_2 = uilabel(app.UIFigure);
            app.nanLabel_2.Text = 'nan';
            app.nanLabel_3 = uilabel(app.UIFigure);
            app.nanLabel_3.Text = 'nan';
            app.OkunanVeriSaysBLabel_2 = uilabel(app.UIFigure);
            app.OkunanVeriSaysBLabel_2.Text = 'Okunan Veri Sayısı(B):';
            app.OkunmayBekleyenBLabel_2 = uilabel(app.UIFigure);
            app.OkunmayBekleyenBLabel_2.Text = 'Okunmayı Bekleyen(B):';
            app.nanLabel_4 = uilabel(app.UIFigure);
            app.nanLabel_4.Text = 'nan';
            app.nanLabel_5 = uilabel(app.UIFigure);
            app.nanLabel_5.Text = 'nan';
            app.OkunanVeriSaysBLabel_3 = uilabel(app.UIFigure);
            app.OkunanVeriSaysBLabel_3.Text = 'Okunan Veri Sayısı(B):';
            app.OkunmayBekleyenBLabel_3 = uilabel(app.UIFigure);
            app.OkunmayBekleyenBLabel_3.Text = 'Okunmayı Bekleyen(B):';
            app.nanLabel_6 = uilabel(app.UIFigure);
            app.nanLabel_6.Text = 'nan';
            app.AirspeedIndicator = uiaeroairspeed(app.UIFigure);
            app.HeadingIndicator = uiaeroheading(app.UIFigure);
            app.Altimeter = uiaeroaltimeter(app.UIFigure);
            app.GyroXLabel = uilabel(app.UIFigure);
            app.GyroXLabel.Text = 'Gyro X:';
            app.GyroYLabel = uilabel(app.UIFigure);
            app.GyroYLabel.Text = 'Gyro Y:';
            app.GyroZLabel = uilabel(app.UIFigure);
            app.GyroZLabel.Text = 'Gyro Z:';
            app.AccelXLabel = uilabel(app.UIFigure);
            app.AccelXLabel.Text = 'Accel X:';
            app.AccelYLabel = uilabel(app.UIFigure);
            app.AccelYLabel.Text = 'Accel Y:';
            app.AccelZLabel = uilabel(app.UIFigure);
            app.AccelZLabel.Text = 'Accel Z:';
            app.rtifaLabel = uilabel(app.UIFigure);
            app.rtifaLabel.Text = 'İrtifa:';
            app.RoketEnlemLabel = uilabel(app.UIFigure);
            app.RoketEnlemLabel.Text = 'Roket Enlem:';
            app.RoketBoylamLabel = uilabel(app.UIFigure);
            app.RoketBoylamLabel.Text = 'Roket Boylam:';
            app.RoketGPSrtifaLabel = uilabel(app.UIFigure);
            app.RoketGPSrtifaLabel.Text = 'Roket GPS İrtifa:';
            app.YkEnlemLabel = uilabel(app.UIFigure);
            app.YkEnlemLabel.Text = 'Yük Enlem:';
            app.YkBoylamLabel = uilabel(app.UIFigure);
            app.YkBoylamLabel.Text = 'Yük Boylam:';
            app.nanLabel_7 = uilabel(app.UIFigure);
            app.nanLabel_7.Text = 'nan';
            app.nanLabel_8 = uilabel(app.UIFigure);
            app.nanLabel_8.Text = 'nan';
            app.nanLabel_9 = uilabel(app.UIFigure);
            app.nanLabel_9.Text = 'nan';
            app.nanLabel_10 = uilabel(app.UIFigure);
            app.nanLabel_10.Text = 'nan';
            app.nanLabel_11 = uilabel(app.UIFigure);
            app.nanLabel_11.Text = 'nan';
            app.nanLabel_12 = uilabel(app.UIFigure);
            app.nanLabel_12.Text = 'nan';
            app.nanLabel_13 = uilabel(app.UIFigure);
            app.nanLabel_13.Text = 'nan';
            app.nanLabel_14 = uilabel(app.UIFigure);
            app.nanLabel_14.Text = 'nan';
            app.nanLabel_15 = uilabel(app.UIFigure);
            app.nanLabel_15.Text = 'nan';
            app.nanLabel_16 = uilabel(app.UIFigure);
            app.nanLabel_16.Text = 'nan';
            app.nanLabel_17 = uilabel(app.UIFigure);
            app.nanLabel_17.Text = 'nan';
            app.nanLabel_18 = uilabel(app.UIFigure);
            app.nanLabel_18.Text = 'nan';
            app.HTML = uihtml(app.UIFigure);
            app.ROKETSwitchLabel = uilabel(app.UIFigure);
            app.ROKETSwitchLabel.Text = 'ROKET';
            app.RoketSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RoketSwitch.Items = {'DUR', 'BASLA'};
            app.RoketSwitch.ValueChangedFcn = createCallbackFcn(app, @RoketSwitchValueChanged, true);
            app.RoketSwitch.Value = 'DUR';
            app.YKSwitchLabel = uilabel(app.UIFigure);
            app.YKSwitchLabel.Text = 'YÜK';
            app.YukSwitch = uiswitch(app.UIFigure, 'toggle');
            app.YukSwitch.Items = {'DUR', 'BASLA'};
            app.YukSwitch.Value = 'DUR';
            app.HYSwitchLabel = uilabel(app.UIFigure);
            app.HYSwitchLabel.Text = 'HYİ';
            app.HYISwitch = uiswitch(app.UIFigure, 'toggle');
            app.HYISwitch.Items = {'DUR', 'BASLA'};
            app.HYISwitch.Value = 'DUR';
            app.ROKETPORTDropDownLabel = uilabel(app.UIFigure);
            app.ROKETPORTDropDownLabel.Text = 'ROKET PORT';
            app.RoketPorts_DropDown = uidropdown(app.UIFigure);
            app.ROKETBAUDDropDownLabel = uilabel(app.UIFigure);
            app.ROKETBAUDDropDownLabel.Text = 'ROKET BAUD';
            app.RoketBauds_DropDown = uidropdown(app.UIFigure);
            app.YKPORTDropDownLabel = uilabel(app.UIFigure);
            app.YKPORTDropDownLabel.Text = 'YÜK PORT';
            app.YukPorts_DropDown = uidropdown(app.UIFigure);
            app.YKBAUDDropDownLabel = uilabel(app.UIFigure);
            app.YKBAUDDropDownLabel.Text = 'YÜK BAUD';
            app.YukBauds_DropDown = uidropdown(app.UIFigure);
            app.HYPORTDropDownLabel = uilabel(app.UIFigure);
            app.HYPORTDropDownLabel.Text = 'HYİ PORT';
            app.HYIPorts_DropDown = uidropdown(app.UIFigure);
            app.HYBAUDDropDownLabel = uilabel(app.UIFigure);
            app.HYBAUDDropDownLabel.Text = 'HYİ BAUD';
            app.HYIBauds_DropDown = uidropdown(app.UIFigure);
            app.ROKETGELENVERLERBYTEARRAYTextAreaLabel = uilabel(app.UIFigure);
            app.ROKETGELENVERLERBYTEARRAYTextAreaLabel.Text = 'ROKET GELEN VERİLER (BYTE ARRAY)';
            app.RoketHamVeriler = uitextarea(app.UIFigure);
            app.CONSOLEIKTISITextAreaLabel = uilabel(app.UIFigure);
            app.CONSOLEIKTISITextAreaLabel.Text = 'CONSOLE ÇIKTISI';
            app.CONSOLEIKTISITextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPEKTRUM_UI_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end